classdef DatosCampo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        TratamientoDropDownLabel  matlab.ui.control.Label
        TratamientoDropDown       matlab.ui.control.DropDown
        RepeticinDropDownLabel    matlab.ui.control.Label
        RepeticinDropDown         matlab.ui.control.DropDown
        VariableDropDownLabel     matlab.ui.control.Label
        VariableDropDown          matlab.ui.control.DropDown
        ValorEditFieldLabel       matlab.ui.control.Label
        ValorEditField            matlab.ui.control.NumericEditField
        DatosdeCampoLabel         matlab.ui.control.Label
        AgregarButton             matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        ExportarButton            matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        tratamientos
        fileNames;
        nombres;
        idBD;
        tData;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tratamientos, fileNames, nombres, idBD)
            app.tratamientos = tratamientos;
            app.fileNames = fileNames;
            app.nombres = nombres;
            app.idBD = idBD;
            
            app.TratamientoDropDown.Items = app.tratamientos;
            app.TratamientoDropDown.ItemsData = app.tratamientos;
            app.RepeticinDropDown.Items = app.fileNames;
            app.RepeticinDropDown.ItemsData = app.fileNames;
            app.VariableDropDown.Items = app.nombres;
            app.VariableDropDown.ItemsData = app.nombres;
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            t = app.TratamientoDropDown.Value;
            r = app.RepeticinDropDown.Value;
            v = app.VariableDropDown.Value;
            n = app.ValorEditField.Value;
            
            t = {convertCharsToStrings(t)};
            r = {convertCharsToStrings(r)};
            v = {convertCharsToStrings(v)};
            array = [n];
            
            T = cell2table(t);
            R = cell2table(r);
            V = cell2table(v);
            N = array2table(array);
            
            row = [T R V N];
            disp(row)
            
            app.tData = [app.tData; row];
            
            app.UITable.Data = app.tData;
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            cd 'C:\HYPER'
            if(app.idBD ~= 0)
                query = sprintf('SELECT nombre FROM andes WHERE id = %d LIMIT 1', app.idBD);
                nombreBD = databaseConnectionSelect(query);
                nombreBD = table2cell(nombreBD);
            else
                nombreBD = cellstr('Local');
            end
            
            mkdir ([nombreBD{1}])
            cd ([nombreBD{1}])
            fecha = datetime('now');
            fecha.Format = 'yyyy-MM-dd HH:mm:ss';
            fecha = datestr(fecha);
            fecha = replace(fecha,":",".");
            mkdir ([fecha])
            cd([fecha])
            
            mkdir Datos de Campo
            cd 'C:\HYPER'
            
            startName = ['C:\HYPER\', fecha , '\Datos de Campo\'];
            endNameDatosCampo = '-DatosDeCampo.csv';
            
            d = uiprogressdlg(app.UIFigure,'Title','Por favor espere', 'Message','Exportando archivos');
            pause(.5);
            
            d.Value = .33;
            d.Message = 'Cargando Archivos';
            pause(1);
            
            
            for i = 1:length(app.tratamientos)
                cd ([startName])
                tratamientoLocal = app.tratamientos(i);
                trat = cellstr(tratamientoLocal);  
                mkdir ([trat{1}]);
                cd 'C:\HYPER'
                localCorrelacion = [];
                BdData = [];
                data = get(app.UITable, 'Data');
                corr = data;
                
                %Encontrar los valores que encajen con el tratamiento
                localFileCampo = strcat(startName,tratamientoLocal,'\',tratamientoLocal,endNameDatosCampo);
                localFileCampo = localFileCampo{1};
                disp(localFileCampo)
                for j = 1: size(corr)
                    campo = corr(j,:);
                    
                    tr = char(tratamientoLocal{1});
                    idtrat = tr(2);
                    id = array2table(idtrat);
                    rep = cell2table({campo.r},'VariableNames',{'rep'});
                    var = cell2table({campo.v},'VariableNames',{'var'});
                    val = array2table(campo.array);
                    
                    row = [rep var val id];
                    disp(row)
                    
                    campo = table2struct(campo);
                    dato = table2struct(row);
                    if(strcmp(campo.t,tratamientoLocal))
                        localCorrelacion = [localCorrelacion campo];
                        BdData = [BdData dato];
                        
                    end
                end
                if(~isempty(localCorrelacion))
                    exportarDatosCampo(localFileCampo,localCorrelacion);
                end 
                d.Value = d.Value + .1; 
                if(~isempty(BdData))
                    if(app.idBD ~=0)
                        for j = 1:length(BdData)
                            inser = BdData(j);
                            r = inser.rep;
                            v = inser.var;
                            n = inser.Var1;
                            auxid = inser.idtrat;
                            insertarDatoCampo(r,v,n,auxid);
                        end
                    end
                end
                
            end
            d.Value = 1;
            app.delete;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TratamientoDropDownLabel = uilabel(app.GridLayout2);
            app.TratamientoDropDownLabel.Text = 'Tratamiento';
            app.TratamientoDropDown = uidropdown(app.GridLayout2);
            app.TratamientoDropDown.Items = {};
            app.TratamientoDropDown.Value = {};
            app.RepeticinDropDownLabel = uilabel(app.GridLayout2);
            app.RepeticinDropDownLabel.Text = 'Repetición';
            app.RepeticinDropDown = uidropdown(app.GridLayout2);
            app.RepeticinDropDown.Items = {};
            app.RepeticinDropDown.Value = {};
            app.VariableDropDownLabel = uilabel(app.GridLayout2);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.GridLayout2);
            app.VariableDropDown.Items = {};
            app.VariableDropDown.Value = {};
            app.ValorEditFieldLabel = uilabel(app.GridLayout2);
            app.ValorEditFieldLabel.Text = 'Valor';
            app.ValorEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ValorEditField.Value = 0.012;
            app.DatosdeCampoLabel = uilabel(app.GridLayout2);
            app.DatosdeCampoLabel.Text = 'Datos de Campo';
            app.AgregarButton = uibutton(app.GridLayout2, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Tratamiento'; 'Repetición'; 'Variable'; 'Valor'};
            app.UITable.RowName = {};
            app.ExportarButton = uibutton(app.GridLayout3, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatosCampo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end