classdef Filtro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        TratamientoDropDownLabel  matlab.ui.control.Label
        TratamientoDropDown       matlab.ui.control.DropDown
        UITable                   matlab.ui.control.Table
        GuardarButton             matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        GraficarButton            matlab.ui.control.Button
        AgregadosLabel            matlab.ui.control.Label
        UITable2                  matlab.ui.control.Table
        Borrar                    matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
        ContinuarButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        tratamientos
        etiquetasTratamientos
        repeticiones
        idBD
        reflectancias
        fileNames
        pathName
        tData
        seleccionados
        wave_new
        data_new
        actuales
    end
    
    methods (Access = private)
        
        function [flag] = contiene(app, list , element)
            flag = 0;
            for i = 1:length(list)
                if(strcmpi(list(i),element)==1)
                    flag = 1;
                    return;
                end
            end
        end
        
        function cargarArchivos(app)
            curr_data = get(app.UITable,'Data');
            app.actuales = [];
            app.reflectancias = [];
            for indice = 1:size(curr_data,1)
                row_changed = indice;
                id_affected = curr_data(row_changed, 1);
                dist_affected = curr_data(row_changed, 2);
                
                array = table2array(dist_affected);
                if(array == 1)
                    arrayId = table2cell(id_affected);
                    nombre = cellstr(arrayId(1));
                    
                    app.actuales = [app.actuales nombre];
                end
            end
            disp('carga de archivos')
            for i = 1:length(app.actuales)
                fileName = app.actuales{i};
                file = fullfile(app.pathName,fileName);
                fid = fopen(file);
                [wavelength, data, new_wave, new_data] = leerArchivo(fileName);
                [app.wave_new, app.data_new] = interpolar(wavelength, data, new_wave, new_data);
                localReflectancia = calculoReflectancia(app.wave_new, app.data_new);
                app.reflectancias = [app.reflectancias , localReflectancia];               
                fclose(fid);                
            end
            app.reflectancias = app.reflectancias.';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tratamientos, repeticiones, idBD)
            app.tratamientos = tratamientos;
            app.repeticiones = repeticiones;
            app.idBD = idBD;
            app.UIFigure.WindowState = 'maximized';
            app.seleccionados = [];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [app.fileNames, app.pathName] = uigetfile("*.sig","MultiSelect","on");
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            cd ([app.pathName])
            
            contador = 0;
            app.etiquetasTratamientos = [];
            app.fileNames = cellstr(app.fileNames);
            disp(length(app.fileNames))
            for i = 1:length(app.fileNames)
                fileName = char(app.fileNames(i));
                t = fileName(1:2);
                t = convertCharsToStrings(t);
                if(isempty(app.etiquetasTratamientos))
                    app.etiquetasTratamientos = [app.etiquetasTratamientos, t];
                    contador = contador + 1;
                else
                    if(contiene(app, app.etiquetasTratamientos, t) == 0)
                        app.etiquetasTratamientos = [app.etiquetasTratamientos, {t}];
                        contador = contador + 1;
                    end
                end
            end
            app.TratamientoDropDown.Items = app.etiquetasTratamientos;
            set(app.TratamientoDropDown,'Value',app.etiquetasTratamientos(1));
            fprintf('contador = %d\n', contador);
            for i = 1:length(app.etiquetasTratamientos)
                disp(app.etiquetasTratamientos(i));
            end
            if(contador < app.tratamientos || contador > app.tratamientos)
                f = msgbox('Cantidad incorrecta de tratamientos','Error','error');
                return;
            else
                %app.fileNames = app.fileNames.';
                %app.UITable.Data = app.fileNames;
            end
        end

        % Value changed function: TratamientoDropDown
        function TratamientoDropDownValueChanged(app, event)
            value = app.TratamientoDropDown.Value;
            lista = [];
            
            for i = 1:length(app.fileNames)
                fileName = app.fileNames(i);
                f = char(fileName);
                f = f(1:2);
                f = convertCharsToStrings(f);
                if(strcmp(value,f))
                    lista = [lista , fileName]
                end
            end
            app.tData = [];
            lista = lista.';
            
            
            w = true(size(lista,1),1);
            tab = table(w);
            
            app.tData = [app.tData lista];
            app.tData = [app.tData tab];
            
            app.UITable.Data = app.tData;
            app.UITable.ColumnEditable = true(1,2);
            app.UITable.ColumnEditable(1) = false;
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            hold(app.UIAxes,'off');
            cargarArchivos(app);
            disp('do something... :c')
            data = [];
            indices = [350:1:2500];
            [m,n] = size(app.reflectancias)
            for i = 1:m
                data = app.reflectancias(i,:);
                disp(length(data))
                plot(app.UIAxes,indices,data);
                hold(app.UIAxes,'on');
            end
            legend(app.UIAxes, app.actuales, "Location","northeast","FontSize",14);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            curr_data = get(app.UITable,'Data');
            for indice = 1:size(curr_data,1)
                row_changed = indice;
                id_affected = curr_data(row_changed, 1);
                dist_affected = curr_data(row_changed, 2);
                
                array = table2array(dist_affected);
                if(array == 1)
                    arrayId = table2cell(id_affected);
                    nombre = cellstr(arrayId(1));
                    if(~app.contiene(app.seleccionados,nombre))
                        app.seleccionados = [app.seleccionados ; nombre];
                    end
                end
            end
            data = [];
            
            w = true(size(app.seleccionados,1),1);
            tab = table(w);
            
            data = [data app.seleccionados];
            data = [data tab];
            
            app.UITable2.Data = data;
            app.UITable2.ColumnEditable = true(1,2);
            app.UITable2.ColumnEditable(1) = false;
        end

        % Button pushed function: Borrar
        function BorrarButtonPushed(app, event)
            curr_data = get(app.UITable2,'Data');
            for indice = 1:size(curr_data,1)
                row_changed = indice;
                id_affected = curr_data(row_changed, 1);
                dist_affected = curr_data(row_changed, 2);
                
                array = table2array(dist_affected);
                if(array == 1)
                    arrayId = table2cell(id_affected);
                    nombre = cellstr(arrayId(1));
                    index = strcmp(app.seleccionados,nombre);
                    app.seleccionados(index) = [];
                end
            end
            data = [];
            data = [data app.seleccionados];
            
            w = true(size(app.seleccionados,1),1);
            tab = table(w);
            
            data = [data tab];
            set(app.UITable2,'Data',data);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {203, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TratamientoDropDownLabel = uilabel(app.GridLayout2);
            app.TratamientoDropDownLabel.Text = 'Tratamiento ';
            app.TratamientoDropDown = uidropdown(app.GridLayout2);
            app.TratamientoDropDown.Items = {};
            app.TratamientoDropDown.ValueChangedFcn = createCallbackFcn(app, @TratamientoDropDownValueChanged, true);
            app.TratamientoDropDown.Value = {};
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.GuardarButton = uibutton(app.GridLayout2, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'openFileIcon.png';
            app.Button.Text = '';
            app.GraficarButton = uibutton(app.GridLayout2, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.AgregadosLabel = uilabel(app.GridLayout2);
            app.AgregadosLabel.Text = 'Agregados';
            app.UITable2 = uitable(app.GridLayout2);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable2.RowName = {};
            app.Borrar = uibutton(app.GridLayout2, 'push');
            app.Borrar.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.Borrar.Text = 'Eliminar';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ContinuarButton = uibutton(app.GridLayout3, 'push');
            app.ContinuarButton.Text = 'Continuar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filtro(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end