classdef IndicesNxM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        CalcularButton        matlab.ui.control.Button
        MarcarTodosButton     matlab.ui.control.Button
        DesmarcarTodosButton  matlab.ui.control.Button
        AgregarndiceButton    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        IndicesFinales % Indices seleccionados a calcular
        IndicesPromediados;
        Reflectancias;
        ReflectanciasProm;
        Tratamientos;
        idBD;
        numRepeticiones;
        fileNames;
    end
    
    methods (Access = private)
        
        function updateplot(app)
            % Get Table UI component data
            %uit = app.RightPanel.UITable.D
            %uit = app.UITable.DisplayData;     
            disp('cambia')
            
            % Plot modified data
            
            %plot(app.UIAxes2,x2,y2,'-o');
        end
        
        function [indicesPromediados, indicesTratamientos] = promediarTratamientos(app, indicesNxM)
            
            largoIndices = size(indicesNxM{1}, 1);
            largoTratamientos = length(app.Tratamientos);
            
            indicesTratamientos = cell(largoTratamientos, 1);
            indicesPromediados = {};
            
            i = 1;
            j = 1;
            while j<=largoTratamientos
                localIndices = {};
                localNumRepts = app.numRepeticiones(j);
                %si el indice es contemplado como repeticion del
                %tratamiento actual
                while i<=length(indicesNxM)
                    if(localNumRepts ~= 0)
                        %fprintf('%d pertenece al tratamiento %d', i, j);
                        %se agrega a un arreglo para promediar
                        localIndices = [localIndices, {indicesNxM{i}}];
                        localNumRepts = localNumRepts - 1;
                        i = i + 1;
                    else
                        break;
                        
                    end
                end
                %promediar indices por cada tratamiento
                valueSet = {};
                if(length(localIndices) > 1)
                    localT = table();
                    for k=1:length(localIndices)
                        newData = cell2table(values(localIndices{k}));                
                        T = [localT;newData]
                        localT = T;
                        
                    end
                    for x=1:largoIndices
                        %promedio por cada columna de la tabla
                        localProm = mean(localT{:,x});
                        valueSet = [valueSet, localProm]; 
                    end
                    
                else
                    valueSet = values(localIndices{1});
                end
                keySet = [keys(indicesNxM{1})];
                %indiceLocal = containers.Map(keySet,values(indicesNxM{j}) );
                indicesTratamiento = containers.Map(keySet,valueSet);
                indicesPromediados = [indicesPromediados, {indicesTratamiento}];
                indicesTratamientos(j) = {localIndices};
                j = j + 1;
            end
            
        end
        
        function [longitudesOnda] = getLongitudesOnda(app, funcion)
            split = regexp(funcion, '[-+*/()]', 'split');
            
            longitudesOnda = [];
            minimo = 350;
            maximo = 2500;
        
            for sub = 1:length(split)            
                local = cell2mat(split(sub));
                local = str2double(local);
        
                if( (~isnan(local)) && (local >= minimo) && (local <= maximo) )
                    disp(sub);
                    disp(local);
        
                    if(isempty(longitudesOnda) )
                        longitudesOnda = [longitudesOnda local];
                    elseif(contiene(app, ismember(longitudesOnda, local)) == 0 )
                        longitudesOnda = [longitudesOnda local];
                    end
                end
            end
        end
        
        function [flag] = contiene(app, array)
            flag = 0;
            for i = 1:length(array)
               if(array(i) == 1)
                  flag = 1;
               end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, reflectancias, etiquetasTratamientos, idBD, numRepeticiones, reflectanciasProm, fileNames)
            app.UIFigure.WindowState = 'maximized';
            
            d = uiprogressdlg(app.UIFigure,'Title','Por favor espere', 'Message','Cargando Archivos');
            pause(.5)
            
            app.Reflectancias = reflectancias;
            app.Tratamientos = etiquetasTratamientos;      
            app.idBD = idBD;
            app.numRepeticiones = numRepeticiones;
            app.ReflectanciasProm = reflectanciasProm;
            app.fileNames = fileNames;
                        
            indicesNxM = {};
            indicesPromediados = {};
            indicesTratamientos = {};
            
            d.Value = .33;
            d.Message = 'Cargando Archivos';
            pause(1);
            
            %Se trabaja de forma local
            if(app.idBD == 0)
                for i = 1:size(app.Reflectancias,1)
                    reflectancia = app.Reflectancias(i,:);
                    indicesMap = indices(reflectancia);
                    
                    indicesNxM = [indicesNxM, {indicesMap}];
                end
            
            else
                %Si existe BD
                for i = 1:size(app.Reflectancias,1)
                    reflectancia = app.Reflectancias(i,:);
                    indicesMap = indicesBD(reflectancia);                   
                    
                    indicesNxM = [indicesNxM, {indicesMap}];
                end
                
            end
            
            [indicesPromediados, indicesTratamientos] = promediarTratamientos(app, indicesNxM);
            disp('indicesT')
            disp(indicesTratamientos);
            disp('1');
            disp(indicesTratamientos(1));
            z = indicesTratamientos(1);
            disp(z{1});
           
            d.Value = .67;
            d.Message = 'Procesando los Datos';
            pause(1);
                
            keySet = keys(indicesNxM{1});
            
            w = true(size(indicesNxM{1}, 1),1);
            table2 = table(w);            
            
            %Transponer indices. Columnas a filas.
            aTableArray = table2array(table(keySet));
            aTableT = array2table(aTableArray');
            
            %checkbox = true(333);
            tdata = [aTableT table2];
            
            d.Value = 1;
            d.Message = 'Finalizando';
            pause(1);
            close(d);

            app.UITable.Data = tdata;
            app.UITable.ColumnEditable = true(1,2);
            app.UITable.ColumnEditable(1) = false;

            
            app.IndicesFinales = indicesTratamientos;            
            app.IndicesPromediados = indicesPromediados;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            updateplot(app);
            disp('cambio');
        end

        % Button pushed function: MarcarTodosButton
        function MarcarTodosButtonPushed(app, event)
            disp('Marcar');
            cur_data = get(app.UITable, 'Data');
            %where_changed = eventdata.Indices;
            disp('size');
            disp(size(cur_data,1));

            row_changed = 1;
            id_affected = cur_data(row_changed, 1);
            dist_affected = cur_data(row_changed, 2);
            
            %desmarcar
            cur_data(:,2) = {true};
            %disp(cur_data(:,2));
            
            set(app.UITable, 'Data', cur_data);
            
            drawnow;
            
            %disp('wher');
            %disp(where_changed);
            disp('row');
            disp(row_changed);
            disp('id');
            disp(id_affected);
            disp('dist');
            disp(dist_affected);
        end

        % Button pushed function: DesmarcarTodosButton
        function DesmarcarTodosButtonPushed(app, event)
            disp('Desmarcar');
            cur_data = get(app.UITable, 'Data');
            %where_changed = eventdata.Indices;
            disp('size');
            disp(size(cur_data,1));

            row_changed = 1;
            id_affected = cur_data(row_changed, 1);
            dist_affected = cur_data(row_changed, 2);
            
            %desmarcar
            cur_data(:,2) = {false};
            %disp(cur_data(:,2));
            
            set(app.UITable, 'Data', cur_data);
            
            drawnow;
            
            %disp('wher');
            %disp(where_changed);
            disp('row');
            disp(row_changed);
            disp('id');
            disp(id_affected);
            disp('dist');
            disp(dist_affected);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            disp('Calcular');
            cur_data = get(app.UITable, 'Data');
            disp('size');
            disp(size(cur_data,1));
            
            indicesFinales = {};
            
            %for, cruce hashmap, return Indicesview
            for i = 1:length(app.IndicesPromediados)
                marcados = containers.Map('KeyType','char', 'ValueType','double');
                localMap =containers.Map('KeyType','char', 'ValueType','double');
                for indice = 1:size(cur_data,1)
                    row_changed = indice;
                    id_affected = cur_data(row_changed, 1);
                    dist_affected = cur_data(row_changed, 2);
                
                    array = table2array(dist_affected);
                    if(array == 1 )
                        %disp(id_affected(1,1));
                        arrayId = table2array(id_affected);
                        nombre = char(arrayId(1));
                        
                        localMap = app.IndicesPromediados{i};
                        marcados(nombre) = localMap(nombre);
                    end    
                end
                indicesFinales = [indicesFinales,{marcados}];
            end
                        
            
            if(size(indicesFinales{1}, 1) > 0)
                %
                IndicesCorrelaciones(app.IndicesFinales, app.Reflectancias, app.Tratamientos, app.idBD, indicesFinales, app.ReflectanciasProm, app.fileNames);
                app.delete
            else
                f = msgbox('Seleccione mínimo un índice', 'Error','error');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app2();
            app.delete;
        end

        % Button pushed function: AgregarndiceButton
        function AgregarndiceButtonPushed(app, event)
            [Answer, Cancelled] = modal();
            
            if(Cancelled == 0)
                %Agregar indice a la tabla
                oldData = get(app.UITable,'Data');
                
                Var1 = {Answer.Nombre};
                funcion = Answer.Funcion;
                w = true(1);
                
                tableNew1 = table(Var1);
                tableNew2 = table(w);            
                tableNew = [tableNew1 tableNew2];
                
                newData = [oldData; tableNew];                
                set(app.UITable,'Data',newData);
                
                %Agregar indice nuevo a cada HashMap
                for i = 1:length(app.Tratamientos)
                    indicesNuevos = agregarNuevoIndice(app.IndicesFinales{i}, app.Reflectancias(i,:), Var1{1}, funcion);
                    app.IndicesFinales{i} = indicesNuevos;
                end   
                %ingresar nuevo índice a la bd
                if(app.idBD ~= 0)
                    longitudesOnda = getLongitudesOnda(app, funcion);
                    localMax = max(longitudesOnda(:));
                    localMin = min(longitudesOnda(:));
        
                    query = sprintf('INSERT INTO indice(nombre, formula, longitudOndaInferior, longitudOndaSuperior, categoria) VALUES(%s%s%s, %s%s%s, %i, %i, %s%s%s) ;', "'", Var1{1}, "'" , "'", funcion, "'", localMin, localMax, "'", 'default', "'");
                    disp(query);
                    databaseConnectionInsert(query);
                end
                
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            readFilesNxM(length(app.Tratamientos), 4, app.idBD);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.CalcularButton = uibutton(app.GridLayout3, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.MarcarTodosButton = uibutton(app.GridLayout3, 'push');
            app.MarcarTodosButton.ButtonPushedFcn = createCallbackFcn(app, @MarcarTodosButtonPushed, true);
            app.MarcarTodosButton.Text = 'Marcar Todos';
            app.DesmarcarTodosButton = uibutton(app.GridLayout3, 'push');
            app.DesmarcarTodosButton.ButtonPushedFcn = createCallbackFcn(app, @DesmarcarTodosButtonPushed, true);
            app.DesmarcarTodosButton.Text = 'Desmarcar Todos';
            app.AgregarndiceButton = uibutton(app.GridLayout3, 'push');
            app.AgregarndiceButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarndiceButtonPushed, true);
            app.AgregarndiceButton.Text = 'Agregar Índice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IndicesNxM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end