classdef ModalBD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        CargarBasedeDatosTab      matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        BasedeDatosDropDownLabel  matlab.ui.control.Label
        BasedeDatosDropDown       matlab.ui.control.DropDown
        AceptarButton             matlab.ui.control.Button
        CrearBasedeDatosTab       matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        NombreEditFieldLabel      matlab.ui.control.Label
        NombreEditField           matlab.ui.control.EditField
        AceptarButton_2           matlab.ui.control.Button
        LocalTab                  matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        ElprogramanoguardarlosarchivosenlaBasedeDatosLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        GridLayout5               matlab.ui.container.GridLayout
        ContinuarButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        idBD
        flag
    end
    
    methods (Access = private)
        
        function llenarDropDown(app)
            conn = database('MySQL','root','');
            
            query = sprintf('SELECT nombre FROM andes;');
            data = table2cell(fetch(conn,query));
            
            app.BasedeDatosDropDown.Items = data;
            
            close(conn);
            clear conn query
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, controlFlag)
            app.UIFigure.Resize = 'off';
            llenarDropDown(app);
            app.flag = controlFlag;
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            disp(app.BasedeDatosDropDown.Value)
            conn = database('MySQL','root','');
            data = app.BasedeDatosDropDown.Value;
            
            query = sprintf('Select id FROM andes WHERE nombre = %s%s%s', "'", data, "'");
            disp(query);
            
            app.idBD = fetch(conn,query);
            app.idBD = cell2mat(table2cell(app.idBD));
            disp(app.idBD);
            
            close(conn);
                        
            clear conn query
            
            if(app.flag == 1)
                readFilesNxM(1,1,app.idBD);
                app.delete;
            elseif(app.flag == 2)
                readFilesNxM(4,4,app.idBD);
                app.delete;
            elseif(app.flag == 3)
                NxM(app.idBD);
                app.delete;
            end
        end

        % Button pushed function: AceptarButton_2
        function AceptarButton_2Pushed(app, event)
            value = app.NombreEditField.Value;
            if(isempty(value))
                msgbox('Ingrese Nombre para Base de Datos','Error','error');
                return;
            end
            conn = database('MySQL','root','');
            
            query = sprintf('SELECT nombre FROM andes;');
            bds = fetch(conn,query);
            bds = cellstr(table2cell(bds));
            
            for i = 1:length(bds)
                bd = bds(i);
                if(strcmpi(bd,value) == 1)
                    msgbox('Base de Datos ya existe en el sistema','Error','error');
                    close(conn);
                    clear conn query
                    return;
                end
            end
            
            query = sprintf('INSERT INTO andes(nombre) VALUES(%s%s%s)', "'",value,"'");
            query2 = sprintf('SELECT LAST_INSERT_ID();');
            disp(query)
            disp(query2)
            
            fetch(exec(conn,query));
            app.idBD = fetch(conn,query2);
            app.idBD = cell2mat(table2cell(app.idBD));
            
            disp(app.idBD)
            
            close(conn);
            clear conn query
            
            if(app.flag == 1)
                readFilesNxM(1,1,app.idBD);
                app.delete;
            elseif(app.flag == 2)
                readFilesNxM(4,4,app.idBD);
                app.delete;
            elseif(app.flag == 3)
                NxM(app.idBD);
                app.delete;
            end            
        end

        % Value changed function: NombreEditField
        function NombreEditFieldValueChanged(app, event)
            value = app.NombreEditField.Value;
            
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            %%Se trabaja de forma local
            app.idBD = 0;
            if(app.flag == 1)
                readFilesNxM(1,1,app.idBD);
                app.delete;
            elseif(app.flag == 2)
                readFilesNxM(4,4,app.idBD);
                app.delete;
            elseif(app.flag == 3)
                NxM(app.idBD);
                app.delete;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.CargarBasedeDatosTab = uitab(app.TabGroup);
            app.CargarBasedeDatosTab.Title = 'Cargar Base de Datos';
            app.GridLayout2 = uigridlayout(app.CargarBasedeDatosTab);
            app.BasedeDatosDropDownLabel = uilabel(app.GridLayout2);
            app.BasedeDatosDropDownLabel.Text = 'Base de Datos';
            app.BasedeDatosDropDown = uidropdown(app.GridLayout2);
            app.BasedeDatosDropDown.Items = {};
            app.BasedeDatosDropDown.Value = {};
            app.AceptarButton = uibutton(app.GridLayout2, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.CrearBasedeDatosTab = uitab(app.TabGroup);
            app.CrearBasedeDatosTab.Title = 'Crear Base de Datos';
            app.GridLayout3 = uigridlayout(app.CrearBasedeDatosTab);
            app.NombreEditFieldLabel = uilabel(app.GridLayout3);
            app.NombreEditFieldLabel.Text = 'Nombre ';
            app.NombreEditField = uieditfield(app.GridLayout3, 'text');
            app.NombreEditField.ValueChangedFcn = createCallbackFcn(app, @NombreEditFieldValueChanged, true);
            app.NombreEditField.Value = 'DataBase';
            app.AceptarButton_2 = uibutton(app.GridLayout3, 'push');
            app.AceptarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AceptarButton_2Pushed, true);
            app.AceptarButton_2.Text = 'Aceptar';
            app.LocalTab = uitab(app.TabGroup);
            app.LocalTab.Title = 'Local';
            app.GridLayout4 = uigridlayout(app.LocalTab);
            app.ElprogramanoguardarlosarchivosenlaBasedeDatosLabel = uilabel(app.GridLayout4);
            app.ElprogramanoguardarlosarchivosenlaBasedeDatosLabel.Text = 'El programa no guardará los archivos en la Base de Datos';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = 'Pulse Continuar para seguir en el modo Local ';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ContinuarButton = uibutton(app.GridLayout5, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalBD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end