classdef NxM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TratamientosEditFieldLabel  matlab.ui.control.Label
        TratamientosEditField       matlab.ui.control.NumericEditField
        RepeticionesEditFieldLabel  matlab.ui.control.Label
        RepeticionesEditField       matlab.ui.control.NumericEditField
        AceptarButton               matlab.ui.control.Button
        GridLayout2                 matlab.ui.container.GridLayout
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        tratamientos;
        repeticiones;
        idBD;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idBD)
            app.UIFigure.WindowState = 'maximized';
            app.idBD = idBD;
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            app.tratamientos = app.TratamientosEditField.Value;
            app.repeticiones = app.RepeticionesEditField.Value;
            
            disp('bdnXm')
            disp(app.idBD);
            readFilesNxM(app.tratamientos, app.repeticiones, app.idBD);
            app.delete;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app2();
            app.delete;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app2();
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TratamientosEditFieldLabel = uilabel(app.GridLayout);
            app.TratamientosEditFieldLabel.Text = 'Tratamientos: ';
            app.TratamientosEditField = uieditfield(app.GridLayout, 'numeric');
            app.TratamientosEditField.Value = 2;
            app.RepeticionesEditFieldLabel = uilabel(app.GridLayout);
            app.RepeticionesEditFieldLabel.Text = 'Repeticiones: ';
            app.RepeticionesEditField = uieditfield(app.GridLayout, 'numeric');
            app.RepeticionesEditField.Value = 4;
            app.AceptarButton = uibutton(app.GridLayout, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NxM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end