classdef correlaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        TratamientoDropDownLabel  matlab.ui.control.Label
        TratamientoDropDown       matlab.ui.control.DropDown
        EditField_2               matlab.ui.control.EditField
        EditField                 matlab.ui.control.NumericEditField
        AgregarButton             matlab.ui.control.Button
        ListoButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        tratamientos
        ids
    end
    
    methods (Access = private)
        
        function llenarDropDown(app)
            app.TratamientoDropDown.Items = app.tratamientos;
            app.TratamientoDropDown.ItemsData = app.ids;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tratamientos, ids)
            app.UIFigure.Resize = 'off';
            app.tratamientos = tratamientos;
            app.ids = ids;
            
            disp(app.tratamientos)
            disp(app.ids)
            
            llenarDropDown(app);
            
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            numValue = app.EditField.Value;
            nomValue = app.EditField_2.Value;
            if(isempty(numValue) || isempty(nomValue))
                msgbox('Ingrese los valores obligatorios','Error','error');
                return;
            end
            id = app.TratamientoDropDown.Value;
            
            insertarCorrelacion(nomValue,numValue,id);
            
            msgbox('Datos ingresados con éxito', 'Enhorabuena', 'modal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AgregarButton = uibutton(app.GridLayout, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Value = 0.5;
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.Value = 'Nombre de Correlacion';
            app.TratamientoDropDownLabel = uilabel(app.GridLayout);
            app.TratamientoDropDownLabel.Text = 'Tratamiento';
            app.TratamientoDropDown = uidropdown(app.GridLayout);
            app.ListoButton = uibutton(app.GridLayout, 'push');
            app.ListoButton.Text = 'Listo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correlaciones(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end