classdef testAndes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Button_3                      matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        ReflectanciaButton            matlab.ui.control.Button
        AcotarButton                  matlab.ui.control.Button
        IndicesButton                 matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        IzquierdaEditFieldLabel       matlab.ui.control.Label
        DerechaEditFieldLabel         matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        IzquierdaEditField            matlab.ui.control.NumericEditField
        DerechaEditField              matlab.ui.control.NumericEditField
        EscalasdelareflectanciaLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        Label3                        matlab.ui.control.Label
        AcotarReflectanciaLabel       matlab.ui.control.Label
        CalcularIndicesLabel          matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        filename;
        pathname;
        reflectancia;
        indices;
        reflectanciaAcotada;

    end
    
    methods (Access = private)
        function actualizarGrafico(app)
            limiteIzquierdo = app.IzquierdaEditField.Value;
            limiteDerecho = app.DerechaEditField.Value;
            
            if limiteIzquierdo >= limiteDerecho 
                return;
            end
            if limiteIzquierdo <= 0 || limiteDerecho <= 0
                return;
            end
            
            app.reflectanciaAcotada = zeros(1,limiteDerecho-limiteIzquierdo);
            [new_reflectancia,valores] = acotarReflectancia(app.reflectancia,limiteIzquierdo,limiteDerecho);
            for i = 1:length(new_reflectancia)
                app.reflectanciaAcotada(i) = new_reflectancia(i);
            end
            
            %nueva reflectancia
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
            plot(app.UIAxes,valores,app.reflectanciaAcotada);
            graficarLimites(app);
        end
        
        function graficarLimites(app)                        
            xline(app.UIAxes,400);
            xline(app.UIAxes,700);
            xline(app.UIAxes,1200);
            xline(app.UIAxes,1300);
            xline(app.UIAxes,2500);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idBD)
            app.UIFigure.WindowState = 'maximized';
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
            set(app.ReflectanciaButton,'Enable','off');
            set(app.AcotarButton,'Enable','off');
            set(app.IndicesButton,'Enable','off');
        end

        % Button pushed function: Button_3
        function FileButtonPushed(app, event)
            [app.filename, app.pathname] = uigetfile({'*.sig'}, 'Seleccionar Archivo...');
            if ~ischar(app.filename)
                return; %usuario cancelo la operacion
            end
            app.EditField.Value = app.filename;
            app.EditField.Editable = false;
            set(app.ReflectanciaButton,'Enable','on');
        end

        % Button pushed function: ReflectanciaButton
        function ReflectanciaButtonPushed(app, event)
            %%hold(app.UIAxes,'off');
            file = fullfile(app.pathname,app.filename);
            [fid, msg] = fopen(file,'r');
            if fid == -1
                error(msg);
            end
            figure(app.UIFigure)
            
            [wavelength, data, new_wave, new_data] = leerArchivo(app.filename);
            [wave_new, data_new] = interpolar(wavelength, data, new_wave, new_data);
            app.reflectancia = calculoReflectancia(wave_new, data_new);
            app.Label.FontSize = 14;
            app.Label2.FontSize = 14;
            app.Label3.FontSize = 14;
            app.Label.Text = 'VIS 400-700nm';  
            app.Label2.Text = 'NIR 700-1200nm';
            app.Label3.Text = 'SWIR 1300-2500nm';
                      
            app.indices = [350:1:2500];
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
            plot(app.UIAxes,app.indices,app.reflectancia);
            graficarLimites(app);            
            fclose(fid);
            set(app.AcotarButton,'Enable','on');
            set(app.IndicesButton,'Enable','on');
        end

        % Button pushed function: AcotarButton
        function AcotarButtonPushed(app, event)
            actualizarGrafico(app);
        end

        % Button pushed function: IndicesButton
        function IndicesButtonPushed(app, event)
            app1(app.reflectancia, app.filename);
            app.delete
        end

        % Button pushed function: Button, Button_2
        function ButtonPushed(app, event)
            app2();
            app.delete;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {632, 632};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.Button_3.Icon = 'openFileIcon.png';
            app.Button_3.Text = '';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.ReflectanciaButton = uibutton(app.GridLayout2, 'push');
            app.ReflectanciaButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectanciaButtonPushed, true);
            app.ReflectanciaButton.Text = 'Reflectancia';
            app.AcotarButton = uibutton(app.GridLayout2, 'push');
            app.AcotarButton.ButtonPushedFcn = createCallbackFcn(app, @AcotarButtonPushed, true);
            app.AcotarButton.Text = 'Acotar';
            app.IndicesButton = uibutton(app.GridLayout2, 'push');
            app.IndicesButton.ButtonPushedFcn = createCallbackFcn(app, @IndicesButtonPushed, true);
            app.IndicesButton.Text = 'Indices';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.IzquierdaEditFieldLabel = uilabel(app.GridLayout2);
            app.IzquierdaEditFieldLabel.Text = 'Izquierda';
            app.DerechaEditFieldLabel = uilabel(app.GridLayout2);
            app.DerechaEditFieldLabel.Text = 'Derecha';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            app.IzquierdaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DerechaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DerechaEditField.Value = 2500;
            app.EscalasdelareflectanciaLabel = uilabel(app.GridLayout2);
            app.EscalasdelareflectanciaLabel.Text = 'Escalas de la reflectancia';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.Label2 = uilabel(app.GridLayout2);
            app.Label2.Text = '';
            app.Label3 = uilabel(app.GridLayout2);
            app.Label3.Text = '';
            app.AcotarReflectanciaLabel = uilabel(app.GridLayout2);
            app.AcotarReflectanciaLabel.Text = 'Acotar Reflectancia';
            app.CalcularIndicesLabel = uilabel(app.GridLayout2);
            app.CalcularIndicesLabel.Text = 'Calcular Indices';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testAndes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end